classdef OoC_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        plotMPFfunctionButton  matlab.ui.control.Button
        xmmDropDown            matlab.ui.control.DropDown
        xmmLabel               matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        plotresponsesButton    matlab.ui.control.Button
        title_text             matlab.ui.control.Label
        Parameter_table        matlab.ui.control.Table
        show_para_Button       matlab.ui.control.Button
        OoC_Image              matlab.ui.control.Image
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        b;Ltm; Lpc;L0;L1;Lst;Lro;Lohc;alpha;beta;gamma; t1; t2;
        Kbm1;Ktms;Ktmb;Kst;Krl;k_ohc;Mbm;Mtms; Mtmb; csub; cBM; cTMS; cTMB; cOHC;
        Cm_total; Cm_lin; c_nl; Rm_lin; Rtl; Rvm;Rvl;Ra0; Ca; iq; eps3;parameter_values;
        load_type;sigma_hb;G_hb_max
 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: show_para_Button
        function show_para_ButtonPushed(app, event)
            x_location_cha = app.xmmDropDown.Value;

            x_location = str2num(x_location_cha);

            x_location = x_location/1000;

            s = tf('s'); % syms s; used to define transfer function

            %%%%%%%%%%%%%%%%;%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % [ubm utms utmb phiOHC phiST ]

            AL = 1;    % Allows us to step down MET sensitivity in all models

            epsLevel = 1;   % Allows us to step down somatic motility in all models

            P_res = 0.4;  %target or preset resting probability

            F_BM = 1e-9;  % harmonic excitation amplitude

            D_x = 10e-6;   % cross-section length, which is the diameter of the OHC

            L_total = 18.5e-3;   % length of the cochlear; 18.5 [mm] for guinea pig

            %%  parameters in "Cochlear_parameter_values" Vipin 2023

            hbm = 7e-6-5.3e-6*x_location/L_total;        % [m]; thickness of the BM;

            hbm0 = 7e-6;           % [m], BM thickness at the base

            app.b   = 80e-6+100e-6*x_location/L_total;        % [m]; width of the BM;

            b0 =  80e-6; % [m], BM width at the base

            app.Ltm = 1e-6*(-46e4*x_location^2+110e2*x_location+82);   % [m]; Length of TM (Tectorial Membrane)

            app.alpha = (16e2*x_location+5.3)*pi/180;   % [Radian]

            index2 = (x_location/L_total)^2;

            app.beta = app.alpha + (9^index2-1)*pi/180;

            app.gamma = 20*pi/180; % angle between RL and BM

            app.t1 = 66*pi/180;                     % [Radian]; theta1

            app.t2 = (490*x_location+60)*pi/180;                   % [Radian]; theta2

            app.L0 = app.b/2;         %Distance to midle OHC

            app.L1 = 10e-6;        % [m]; Distance between OHC2-3

            app.Lpc = 0.32*app.b;

            app.Lst = 1e-6*(1+5*(x_location/L_total));  % [m]; length of the stereocilia/HB

            app.Lro = (app.L0-app.Lpc*cos(app.t1))/cos(app.alpha); % from JAP2020

            %%%%%%%%%%%%%%%%%%%%%%%%%% OHC parameters %%%%%%%%%%%%%%%%%%%%%%%

            app.Lohc = (7.514e4*x_location^2+1.565e3*x_location+17)*1e-6;

            app.k_ohc = 500e-9/app.Lohc;

            Mbm1 = 4.2e-7*exp(160*x_location)*D_x; % kg

            if x_location == 7.5e-3  % from Santos-Sacchi "low-pass" paper, assuming an exponential distribution of tau_nl along the cochlea

                app.Kbm1 = 0.12; 2.16e5*exp(-380*x_location)*D_x; % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms = 0.04; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb = 0.03; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.01; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  = 7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 8.0e-11;     %  resonance around 5.8kHz

                tau_nl = 0; 46.75e-6;

                app.iq = 0.65;

                app.Rm_lin = 32.45e6;

                app.Ra0 = 1/16e-9/3; 58.82*exp(300*x_location)/D_x/3;

                app.Ca =  1.6e-12*3; %F

                app.Cm_lin = 11.3e-12;

                app.c_nl = 11.55e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.058;

                app.G_hb_max = 75;

            elseif x_location == 12.5e-3

                app.Kbm1 =  0.019; 2.16e5*exp(-380*x_location)*D_x; % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms =  0.006; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb =  0.006; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.004; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  =  7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 23e-11;    % resonance around 0.697kHz

                tau_nl = 0; 63.69e-6;

                app.iq =   0.21;   0.15;

                app.Rm_lin = 4.577811348394332e+07;

                app.Ra0 = 1/10e-9/3;     58.82*exp(300*x_location)/D_x/3;

                app.Ca = 1.5e-12*3; %F

                app.Cm_lin = 16e-12;

                app.c_nl = 16e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.03;

                app.G_hb_max = 47;

            elseif x_location == 4.15e-3

                app.Kbm1 = 0.45; 2.16e5*exp(-380*x_location)*D_x;  % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms = 0.08; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb = 0.05; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.04; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  = 7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 4.5e-11;      % resonance around 13.57kHz

                tau_nl =  0;

                app.iq =   1*1.080;   % Gmet_max = 90 [nS]; phi_ohc = 150 [mV]

                app.Rm_lin = 2.808e+07;  5.8e5;

                app.Ra0 = 1/(27e-9)/3;     58.82*exp(300*x_location)/D_x/3;

                app.Ca =  1.4e-12*3;   % F

                app.Cm_lin = 8.7e-12;

                app.c_nl = 9.5e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.076;

                app.G_hb_max = 95;

            end

            app.Mbm = Mbm1+Mfluid ;

            app.Mtms = Mtm;

            Ya = (1/app.Ra0+s*app.Ca);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% damping %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.csub = 1e-3*app.Ltm/app.Lst*(D_x);  % [N*s/m] subtectorial space damping

            app.cTMS =  8.000000000000000e-07/2;  % [N*s/m]

            app.cBM  =  5.000000000000001e-07/5;   % [N*s/m]

            app.cTMB =  4.000000000000000e-07/4;    % [N*s/m]

            app.cOHC =  1.600000000000000e-07;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OHC time rate %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            kappa_ohc = 1/app.k_ohc;   % [m/N], OHC compliance static

            kappa_nl = 10;   % approximated by boltzmann distribution

            kappa_e = kappa_ohc-kappa_nl;

            k_e = 1/kappa_e;  % [N/m], stiffness of elastic part

            k_nl = 1/kappa_nl;   % [N/m], stiffness of motor part

            d_nl = -1.1e-7*kappa_ohc;   %[N/m]

            tau_e = 0.0*tau_nl;    % 10mm

            kappa_ohc_tilde = kappa_e/(1+s*tau_e)+kappa_nl/(1+s*tau_nl);

            k_ohc_tilde = 1/kappa_ohc_tilde;

            Kohc = k_ohc_tilde;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% electric parameters %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            d_nl_tilde = d_nl/(1+s*tau_nl);

            c_mnl_tilde = app.c_nl/(1+s*tau_nl)-d_nl_tilde^2*Kohc;

            Ym = 3*(1/app.Rm_lin+s*app.Cm_lin)+3*c_mnl_tilde*s;

            app.eps3 = epsLevel*d_nl_tilde*Kohc;

            app.Rtl = 4/D_x;        %Ohm

            app.Rvm = 25/D_x;      %Ohm

            app.Rvl = 10/D_x;      %Ohm

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% parameters write to APP %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            parameters_name = {'b','L_pc','L_st','L_ro','L_ohc','L_0','L_1','α','β','γ','θ1','θ2',...
                'K_bm','K_tms','K_tmb','K_hb','K_rl','K_ohc','M_bm','M_tms','M_tmb','c_sub','c_BM','c_TMS','c_TMB','c_OHC',...
                'Cm','C_lin','C_nl','Rm','R_tl','R_vm','R_vl','Ra','Ca','ε_ohc','σ_hb','G_hb_max'}';

            parameters_value = [(app.b*1e6) (app.Lpc*1e6) app.Lst*1e6 app.Lro*1e6 app.Lohc*1e6 app.L0*1e6 app.L1*1e6 app.alpha*180/pi app.beta*180/pi app.gamma*180/pi app.t1*180/pi app.t2*180/pi...
                app.Kbm1 app.Ktms app.Ktmb app.Kst app.Krl app.k_ohc 1e9*app.Mbm 1e9*app.Mtms 1e9*app.Mtmb app.csub*1e9 app.cBM*1e9 app.cTMS*1e9 app.cTMB*1e9 app.cOHC*1e9...
                app.Cm_total*1e12 app.Cm_lin*1e12 app.c_nl*1e12 app.Rm_lin/1e6 app.Rtl/1e6 app.Rvm/1e6 app.Rvl/1e6 app.Ra0*3/1e6 app.Ca/3*1e12 evalfr(app.eps3*1e6,0) app.sigma_hb app.G_hb_max]';

            parameters_unit = {'μm','μm','μm','μm','μm','μm','μm','deg','deg','deg','deg','deg',...
                'N/m','N/m','N/m','N/m','N/m','N/m','μg','μg','μg','nN*m/s','nN*m/s','nN*m/s','nN*m/s','nN*m/s',...
                'pF','pF','pF','MΩ','MΩ','MΩ','MΩ','MΩ','pF','nN/mV','1/nm','ns'}';
            parameters_description = {'BM width','distance between points A and B','HB length','distance between points C and G','OHC length','distance between points A and E','distance between points K and G','RL angle','HB angle','TM angle','PC angle','PC angle',...
                'BM stiffness','TM shear stiffness','TM bend stiffness','HB stiffness','RL stiffness','OHC stiffness','BM+fluid mass','TM shear mass','TM bend mass','subtectorial space damping','BM damping','TM shear damping','TM bend damping','OHC deformation damping',...
                'OHC membrane total capacitance','OHC membrane linear capacitance','OHC membrane nonlinear capacitance','OHC membrane resistance','resistance flowing through ST','resistance flowing from SV to SM','resistance flowing through SV','OHC apical resistance',...
                'OHC apical capacitance','OHC electromechanical coupling','slope of MET channel open probability function','maximum saturating conductance of MET channels'}';

            table_parameters = table(parameters_name,parameters_value,parameters_unit,parameters_description);

            app.Parameter_table.Data = table_parameters;

            s = uistyle('HorizontalAlignment','center');

            addStyle(app.Parameter_table,s,'table','');
        end

        % Button pushed function: plotresponsesButton
        function plotresponsesButtonPushed(app, event)

            x_location_cha = app.xmmDropDown.Value;

            x_location = str2num(x_location_cha);

            x_location = x_location/1000;

            s = tf('s'); % syms s; used to define transfer function

            %%%%%%%%%%%%%%%%;%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % [ubm utms utmb phiOHC phiST ]

            AL = 1;    % Allows us to step down MET sensitivity in all models

            epsLevel = 1;   % Allows us to step down somatic motility in all models

            P_res = 0.4;  %target or preset resting probability

            F_BM = 1e-9;  % harmonic excitation amplitude

            D_x = 10e-6;   % cross-section length, which is the diameter of the OHC

            L_total = 18.5e-3;   % length of the cochlear; 18.5 [mm] for guinea pig

            %%  parameters in "Cochlear_parameter_values" Vipin 2023

            hbm = 7e-6-5.3e-6*x_location/L_total;        % [m]; thickness of the BM;

            hbm0 = 7e-6;           % [m], BM thickness at the base

            app.b   = 80e-6+100e-6*x_location/L_total;        % [m]; width of the BM;

            b0 =  80e-6; % [m], BM width at the base

            app.Ltm = 1e-6*(-46e4*x_location^2+110e2*x_location+82);   % [m]; Length of TM (Tectorial Membrane)

            app.alpha = (16e2*x_location+5.3)*pi/180;   % [Radian]

            index2 = (x_location/L_total)^2;

            app.beta = app.alpha + (9^index2-1)*pi/180;

            app.gamma = 20*pi/180; % angle between RL and BM

            app.t1 = 66*pi/180;                     % [Radian]; theta1

            app.t2 = (490*x_location+60)*pi/180;                   % [Radian]; theta2

            app.L0 = app.b/2;         %Distance to midle OHC

            app.L1 = 10e-6;        % [m]; Distance between OHC2-3

            app.Lpc = 0.32*app.b;

            app.Lst = 1e-6*(1+5*(x_location/L_total));  % [m]; length of the stereocilia/HB

            app.Lro = (app.L0-app.Lpc*cos(app.t1))/cos(app.alpha); % from JAP2020

            %%%%%%%%%%%%%%%%%%%%%%%%%% OHC parameters %%%%%%%%%%%%%%%%%%%%%%%

            app.Lohc = (7.514e4*x_location^2+1.565e3*x_location+17)*1e-6;

            app.k_ohc = 500e-9/app.Lohc;

            Mbm1 = 4.2e-7*exp(160*x_location)*D_x; % kg

            if x_location == 7.5e-3  % from Santos-Sacchi "low-pass" paper, assuming an exponential distribution of tau_nl along the cochlea

                app.Kbm1 = 0.12; 2.16e5*exp(-380*x_location)*D_x; % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms = 0.04; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb = 0.03; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.01; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  = 7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 8.0e-11;     %  resonance around 5.8kHz

                tau_nl = 0; 46.75e-6;

                app.iq = 0.65;

                app.Rm_lin = 32.45e6;

                app.Ra0 = 1/16e-9/3; 58.82*exp(300*x_location)/D_x/3;

                app.Ca =  1.6e-12*3; %F

                app.Cm_lin = 11.3e-12;

                app.c_nl = 11.55e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.058;

                app.G_hb_max = 75;

            elseif x_location == 12.5e-3

                app.Kbm1 =  0.019; 2.16e5*exp(-380*x_location)*D_x; % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms =  0.006; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb =  0.006; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.004; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  =  7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 23e-11;    % resonance around 0.697kHz

                tau_nl = 0; 63.69e-6;

                app.iq =   0.21;   0.15;

                app.Rm_lin = 4.577811348394332e+07;

                app.Ra0 = 1/10e-9/3;     58.82*exp(300*x_location)/D_x/3;

                app.Ca = 1.5e-12*3; %F

                app.Cm_lin = 16e-12;

                app.c_nl = 16e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.03;

                app.G_hb_max = 47;

            elseif x_location == 4.15e-3

                app.Kbm1 = 0.45; 2.16e5*exp(-380*x_location)*D_x;  % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms = 0.08; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb = 0.05; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.04; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  = 7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 4.5e-11;      % resonance around 13.57kHz

                tau_nl =  0;

                app.iq =   1*1.080;   % Gmet_max = 90 [nS]; phi_ohc = 150 [mV]

                app.Rm_lin = 2.808e+07;  5.8e5;

                app.Ra0 = 1/(27e-9)/3;     58.82*exp(300*x_location)/D_x/3;

                app.Ca =  1.4e-12*3;   % F

                app.Cm_lin = 8.7e-12;

                app.c_nl = 9.5e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.076;

                app.G_hb_max = 95;

            end

            app.Mbm = Mbm1+Mfluid ;

            app.Mtms = Mtm;

            Ya = (1/app.Ra0+s*app.Ca);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% damping %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.csub = 1e-3*app.Ltm/app.Lst*(D_x);  % [N*s/m] subtectorial space damping

            app.cTMS =  8.000000000000000e-07/2;  % [N*s/m]

            app.cBM  =  5.000000000000001e-07/5;   % [N*s/m]

            app.cTMB =  4.000000000000000e-07/4;    % [N*s/m]

            app.cOHC =  1.600000000000000e-07;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OHC time rate %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            kappa_ohc = 1/app.k_ohc;   % [m/N], OHC compliance static

            kappa_nl = 10;   % approximated by boltzmann distribution

            kappa_e = kappa_ohc-kappa_nl;

            k_e = 1/kappa_e;  % [N/m], stiffness of elastic part

            k_nl = 1/kappa_nl;   % [N/m], stiffness of motor part

            d_nl = -1.1e-7*kappa_ohc;   %[N/m]

            tau_e = 0.0*tau_nl;    % 10mm

            kappa_ohc_tilde = kappa_e/(1+s*tau_e)+kappa_nl/(1+s*tau_nl);

            k_ohc_tilde = 1/kappa_ohc_tilde;

            Kohc = k_ohc_tilde;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% electric parameters %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            d_nl_tilde = d_nl/(1+s*tau_nl);

            c_mnl_tilde = app.c_nl/(1+s*tau_nl)-d_nl_tilde^2*Kohc;

            Ym = 3*(1/app.Rm_lin+s*app.Cm_lin)+3*c_mnl_tilde*s;

            app.eps3 = epsLevel*d_nl_tilde*Kohc;

            app.Rtl = 4/D_x;        %Ohm

            app.Rvm = 25/D_x;      %Ohm

            app.Rvl = 10/D_x;      %Ohm

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% matrix formulation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            psi_ap = sin(pi*app.Lpc/app.b);

            psi_ohc1 = sin(pi*(app.L0-app.L1/cos(app.alpha))/app.b);

            psi_ohc2  = sin(pi*(app.L0)/app.b);

            psi_ohc3 = sin(pi*(app.L0+app.L1/cos(app.alpha))/app.b);

            A1 = -(psi_ap*sin(app.t1+app.t2)/sin(app.t2)*cos(app.t1-app.beta)/cos(app.alpha-app.beta)+psi_ap/app.Lpc*app.Lro); % theta2-theta1

            A2 = -(sin(app.gamma)-cos(app.gamma)*tan(app.alpha-app.beta));

            A3 = cos(app.gamma)+sin(app.gamma)*tan(app.alpha-app.beta);

            A4 = -psi_ap*sin(app.t1+app.t2)/sin(app.t2)*(sin(app.t1-app.alpha)/cos(app.alpha-app.beta)-cos(app.t1-app.beta)/cos(app.alpha-app.beta)*app.Lst/app.Lro);

            A5 = -cos(app.gamma)/cos(app.alpha-app.beta)+(sin(app.gamma)-cos(app.gamma)*tan(app.alpha-app.beta))*app.Lst/app.Lro;

            A6 = -sin(app.gamma)/cos(app.alpha-app.beta)-(cos(app.gamma)+sin(app.gamma)*tan(app.alpha-app.beta))*app.Lst/app.Lro;

            A7 = -psi_ap*sin(app.t1+app.t2)/sin(app.t2)*cos(app.t1-app.alpha);

            A8 = psi_ap*sin(app.t1+app.t2)/sin(app.t2)*cos(app.t1-app.beta)/cos(app.alpha-app.beta);

            A9 = sin(app.gamma)-cos(app.gamma)*tan(app.alpha-app.beta);

            A10 = -(cos(app.gamma)+sin(app.gamma)*tan(app.alpha-app.beta));

            A11 = 1-app.L1/app.Lro;

            A12 = 1+app.L1/app.Lro;

            A13 = psi_ohc1*cos(app.alpha);

            A14 = psi_ohc2*cos(app.alpha);

            A15 = psi_ohc3*cos(app.alpha);

            AA1 = 3*A7+A8*(A11+1+A12)+A13+A14+A15;

            AA2 = A11*A9+A9+A12*A9;

            AA3 = A11*A10+A10+A12*A10;

            B11 = A7+A11*A8+A13;

            B21 = A11*A9;

            B31 = A11*A10;

            B12 = A7+A8+A14;

            B22 = A9;

            B32 = A10;

            B13 = A7+A12*A8+A15;

            B23 = A12*A9;

            B33 = A12*A10;

            C1_damping = -psi_ap*sin(app.t1+app.t2)/sin(app.t2)*sin(app.t1-app.alpha)/cos(app.alpha-app.beta);

            C2_damping = -1/cos(app.alpha-app.beta);

            % stiffnessess corresponding to the difference dof (and coupling terms)

            k11 = app.Kbm1+app.Krl*A1^2+3*app.Kst*A4^2+Kohc*(B11^2+B12^2+B13^2);

            k12 = app.Krl*A1*A2+3*app.Kst*A4*A5+Kohc*(B21*B11+B22*B12+B23*B13);

            k13 = app.Krl*A1*A3+3*app.Kst*A4*A6+Kohc*(B31*B11+B32*B12+B33*B13);

            k14 = app.eps3*AA1;

            k15 = -app.eps3*AA1;

            k21 = app.Krl*A1*A2+3*app.Kst*A4*A5+Kohc*(B11*B21+B12*B22+B13*B23);

            k22 = app.Ktms+app.Krl*A2^2+3*app.Kst*A5^2+Kohc*(B21^2+B22^2+B23^2);

            k23 = app.Krl*A2*A3+3*app.Kst*A5*A6+Kohc*(B31*B21+B32*B22+B33*B23);

            k24 = app.eps3*AA2;

            k25 = -app.eps3*AA2;

            k31 = app.Krl*A1*A3+3*app.Kst*A4*A6+Kohc*(B11*B31+B12*B32+B13*B33);

            k32 = app.Krl*A2*A3+3*app.Kst*A5*A6+Kohc*(B21*B31+B22*B32+B23*B33);

            k33 = app.Ktmb+app.Krl*A3^2+3*app.Kst*A6^2+Kohc*(B31^2+B32^2+B33^2);

            k34 = app.eps3*AA3;

            k35 = -app.eps3*AA3;

            % damping coefficients

            c11 = app.cBM+app.csub*A4^2+app.cOHC*(B11^2+B12^2+B13^2);

            c22 = app.cTMS+app.csub*A5^2+app.cOHC*(B21^2+B22^2+B23^2);

            c33 = app.cTMB+app.cOHC*(B31^2+B32^2+B33^2)+app.csub*A6^2;

            c12 = app.csub*A4*A5+app.cOHC*(B11*B21+B12*B22+B13*B23);

            c13 = app.cOHC*(B11*B31+B12*B32+B13*B33)+app.csub*A4*A6;

            c21 = c12;

            c23 = app.cOHC*(B21*B31+B22*B32+B23*B33)+app.csub*A5*A6;

            c31 = c13;

            c32 = c23;

            Ms = [app.Mbm 0 0 ;0 app.Mtms 0;0 0 app.Mtmb];%Structural Mass

            Ks = [k11 k12 k13; k21 k22 k23; k31 k32 k33] ;% Structural Stiffness

            Cs = [c11 c12 c13; c21 c22 c23; c31 c32 c33]; % Structural Damping

            Cse = zeros(3,2);

            Kse = [k14 k15; k24 k25; k34 k35]; %somatic force generation

            % electrical matrix

            Cr1 = (1/app.Rvm)/(1/app.Rvl+1/app.Rvm); % eq 8: phi_sv=Cr1*phi_sm

            Cr2 = (1/app.Rtl)/((Cr1-1)/app.Rvm); % eqs 9-11:phi_sm=Cr2*phi_st

            Ce = [0 0 ; 0 0];

            Ke = [-(Ya+Ym) Cr2*Ya+Ym ; Ym -1/app.Rtl-Ym];

            Ces = -[-app.eps3*AA1 -app.eps3*AA2 -app.eps3*AA3; app.eps3*AA1 app.eps3*AA2 app.eps3*AA3];

            Kes = -3*app.iq*[A4 A5 A6; 0 0 0];

            % the minus sign in Kes is because of the positive direction in the model derivation, the positive uhb is defined in the HB close direction

            Mse = zeros(3,2);

            Mes = zeros(2,3);

            Me  = zeros(2,2);

            %% responses calculation

            M = [Ms Mse; Mes Me;];

            C = [Cs Cse; Ces Ce];

            K = [Ks Kse; Kes Ke];

            Kdyn_active = ([Ks Kse;Kes Ke]+[Cs Cse;Ces Ce]*s +[Ms Mse; Mes Me]*s^2);

            Kdyn_passive = (Ms*s^2+Cs*s+Ks);

            % Y_all = 1/(Ya+Ym);

            % Y_all1 = 2458/( 1.843e-07*s + 0.0005447);

            Y_all = 1/(Ya+Ym+app.Rtl*Ya*Ym*(1-Cr2));

            coe_Zm1 = 3*app.eps3*app.iq*Y_all;

            % inf_Zm1 = coe_Zm1*[AA1*A4 AA1*A5 AA1*A6; AA2*A4 AA2*A5 AA2*A6; AA3*A4 AA3*A5 AA3*A6];

            inf_Zm1 = Kse*inv(Ke)*(Ces*s+Kes);

            Kdyn_active1 = Ms*s^2+Cs*s+Ks-inf_Zm1;

            TF_active = eye(5,5)/(Kdyn_active);

            poles_active = pole(TF_active);

            if find(real(poles_active)>0)

                line_style_active = '--';

            else

                line_style_active = '-';

            end

            TF_active_resp = TF_active*[F_BM;0;0;0;0];

            TF_passive = eye(3,3)/(Kdyn_passive);

            poles_passive = pole(TF_passive);

            if find(real(poles_passive)>0)

                line_style_passive = '--';

            else

                line_style_passive = '-';

            end

            TF_passive_resp = TF_passive*[F_BM;0;0];

            num =  10000;

            if x_location == 4.15e-3

                fr =  logspace(3.5,5,num);  % x=4mm

            elseif x_location == 12.5e-3

                fr =  logspace(2.9,3.5,num);  % x=15mm

            elseif x_location == 7.5e-3

                fr =  logspace(3.2,4.2,num);    % x=10mm
            end

            w  = 2*pi*fr;

            resp_active = nan(num,5);

            resp_passive = nan(num,3);

            inf_Zm1_value = nan(num,1);

            inf_Zm2_value = nan(num,1);

            Y_all_value = nan(num,1);

            Omega1 = nan(num,1);

            s = 0;

            Ks1 = evalfr(Ks,s);

            % [phi_mode,lambda] = eig(Ks1,Ms);

            s = tf('s');

            for iii = 1:num

                freq = fr(iii);

                s = 2*pi*freq*j;

                resp_active(iii,:) = evalfr(TF_active_resp,s);

                resp_passive(iii,:) = evalfr(TF_passive_resp,s);

            end

            %%

            ubm_active = resp_active(:,1);

            utms_active = resp_active(:,2);

            utmb_active = resp_active(:,3);

            ubm_passive = resp_passive(:,1);

            utms_passive = resp_passive(:,2);

            utmb_passive = resp_passive(:,3);

            figure('Visible', 'off');

            loglog(app.UIAxes, fr, (abs(ubm_passive))*1e9,'linewidth',2); hold(app.UIAxes,'on');

            loglog(app.UIAxes, fr, (abs(ubm_active))*1e9,'linewidth',2);  hold(app.UIAxes,'off');

            legend(app.UIAxes,'BM passive','BM active');

            loglog(app.UIAxes2, fr, (abs(utms_passive))*1e9,'linewidth',2); hold(app.UIAxes2,'on');

            loglog(app.UIAxes2, fr, (abs(utms_active))*1e9,'linewidth',2);   hold(app.UIAxes2,'off');

            legend(app.UIAxes2,'TMS passive','TMS active'); 

            loglog(app.UIAxes3, fr, (abs(utmb_passive))*1e9,'linewidth',2); hold(app.UIAxes3,'on');

            loglog(app.UIAxes3, fr, (abs(utmb_active))*1e9,'linewidth',2);  hold(app.UIAxes3,'off');

            legend(app.UIAxes3,'TMB passive','TMB active'); 

        end

        % Callback function
        function Parameter_tableCellEdit(app, event)
 
        end

        % Display data changed function: Parameter_table
        function Parameter_tableDisplayDataChanged(app, event)
            newDisplayData = app.Parameter_table.DisplayData;
            
        end

        % Cell edit callback: Parameter_table
        function Parameter_tableCellEdit2(app, event)
            indices = event.Indices;
            newData = event.NewData;
            row = indices(1);
            if row ==1
                app.b = newData/1e3;
            elseif row == 2
                app.Ltm = newData/1e3;
            elseif row == 3
                app.Lpc = newData/1e3;
            elseif row == 4
                app.L0 = newData/1e3;
            elseif row == 5
                app.L1 = newData/1e3;
            elseif row == 6
                app.Lst = newData/1e3;
            elseif row == 7
                app.Lro = newData/1e3;
            elseif row == 8
                app.Lohc = newData/1e6;
            elseif row == 9
                app.alpha = newData*pi/180;
            elseif row == 10
                app.beta = newData*pi/180;
            elseif row == 11  
                app. gamma = newData*pi/180;
            elseif row == 12
                app.t1 = newData*pi/180;
            elseif row == 13
                app.t2 = newData*pi/180;
            elseif row == 14
                app.Kbm1 = newData;
            elseif row == 15
                app.Ktms = newData;
            elseif row == 16
                app.Ktmb = newData;
            elseif row ==17
                app.Kst = newData;
            elseif row == 18
                app.Krl = newData;
            elseif row == 19
                app.k_ohc = newData;
            elseif row == 20
                app.Mbm = newData/1e9;
            elseif row == 21
                app.Mtms = newData/1e9;
            elseif row == 22
                app.Mtmb = newData/1e9;
            elseif row == 23
                app.csub = newData/1e9;
            elseif row == 24
                app.cBM = newData/1e9;
            elseif row == 25
                app.cTMS = newData/1e9;
            elseif row == 26
                app.cTMB = newData/1e9;
            elseif row == 27
                app.cOHC = newData/1e9;
            elseif row == 28
                app.Cm_total = newData/1e12;
            elseif row == 29
                app.Cm_lin = newData/1e12;
            elseif row == 30
                app.c_nl = newData/1e12;
            elseif row == 31
                app.Rm_lin = newData*1e6;
            elseif row == 32
                app.Rtl = newData*1e6;
            elseif row == 33
                app.Rvm = newData*1e6;
            elseif row == 34
                app.Rvl = newData*1e6;
            elseif row == 35 
                app.Ra0 = newData*1e6;
            elseif row == 36
                app.Ca = newData/1e12;
            elseif row == 37
                app.iq = newData;
            elseif row == 38
                app.eps3 = newData/1e6;

            end

        end

        % Callback function
        function LoadDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Callback function
        function LoadDropDownValueChanged(app, event)
            value = app.LoadDropDown.Value;
            app.load_type = value;
            
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Button pushed function: plotMPFfunctionButton
        function plotMPFfunctionButtonPushed(app, event)
            
            x_location_cha = app.xmmDropDown.Value;

            x_location = str2num(x_location_cha);

            x_location = x_location/1000;

            s = tf('s'); % syms s; used to define transfer function

            %%%%%%%%%%%%%%%%;%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % [ubm utms utmb phiOHC phiST ]

            AL = 1;    % Allows us to step down MET sensitivity in all models

            epsLevel = 1;   % Allows us to step down somatic motility in all models

            P_res = 0.4;  %target or preset resting probability

            F_BM = 1e-9;  % harmonic excitation amplitude

            D_x = 10e-6;   % cross-section length, which is the diameter of the OHC

            L_total = 18.5e-3;   % length of the cochlear; 18.5 [mm] for guinea pig

            %%  parameters in "Cochlear_parameter_values" Vipin 2023

            hbm = 7e-6-5.3e-6*x_location/L_total;        % [m]; thickness of the BM;

            hbm0 = 7e-6;           % [m], BM thickness at the base

            app.b   = 80e-6+100e-6*x_location/L_total;        % [m]; width of the BM;

            b0 =  80e-6; % [m], BM width at the base

            app.Ltm = 1e-6*(-46e4*x_location^2+110e2*x_location+82);   % [m]; Length of TM (Tectorial Membrane)

            app.alpha = (16e2*x_location+5.3)*pi/180;   % [Radian]

            index2 = (x_location/L_total)^2;

            app.beta = app.alpha + (9^index2-1)*pi/180;

            app.gamma = 20*pi/180; % angle between RL and BM

            app.t1 = 66*pi/180;                     % [Radian]; theta1

            app.t2 = (490*x_location+60)*pi/180;                   % [Radian]; theta2

            app.L0 = app.b/2;         %Distance to midle OHC

            app.L1 = 10e-6;        % [m]; Distance between OHC2-3

            app.Lpc = 0.32*app.b;

            app.Lst = 1e-6*(1+5*(x_location/L_total));  % [m]; length of the stereocilia/HB

            app.Lro = (app.L0-app.Lpc*cos(app.t1))/cos(app.alpha); % from JAP2020

            %%%%%%%%%%%%%%%%%%%%%%%%%% OHC parameters %%%%%%%%%%%%%%%%%%%%%%%

            app.Lohc = (7.514e4*x_location^2+1.565e3*x_location+17)*1e-6;

            app.k_ohc = 500e-9/app.Lohc;

            Mbm1 = 4.2e-7*exp(160*x_location)*D_x; % kg

            if x_location == 7.5e-3  % from Santos-Sacchi "low-pass" paper, assuming an exponential distribution of tau_nl along the cochlea

                app.Kbm1 = 0.12; 2.16e5*exp(-380*x_location)*D_x; % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms = 0.04; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb = 0.03; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.01; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  = 7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 8.0e-11;     %  resonance around 5.8kHz

                tau_nl = 0; 46.75e-6;

                app.iq = 0.65;

                app.Rm_lin = 32.45e6;

                app.Ra0 = 1/16e-9/3; 58.82*exp(300*x_location)/D_x/3;

                app.Ca =  1.6e-12*3; %F

                app.Cm_lin = 11.3e-12;

                app.c_nl = 11.55e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.058;

                app.G_hb_max = 75;

            elseif x_location == 12.5e-3

                app.Kbm1 =  0.019; 2.16e5*exp(-380*x_location)*D_x; % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms =  0.006; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb =  0.006; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.004; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  =  7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 23e-11;    % resonance around 0.697kHz

                tau_nl = 0; 63.69e-6;

                app.iq =   0.21;   0.15;

                app.Rm_lin = 4.577811348394332e+07;

                app.Ra0 = 1/10e-9/3;     58.82*exp(300*x_location)/D_x/3;

                app.Ca = 1.5e-12*3; %F

                app.Cm_lin = 16e-12;

                app.c_nl = 16e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.03;

                app.G_hb_max = 47;

            elseif x_location == 4.15e-3

                app.Kbm1 = 0.45; 2.16e5*exp(-380*x_location)*D_x;  % N/m stiffness of BM 1st mode

                app.Kst  = 41.1/1e3*exp(-238*x_location);    % Stiffness of sensory-cell hair bundles in the isolated guinea pig cochlea

                app.Ktms = 0.08; 2.4e4*exp(-380*x_location)*D_x /2; % N/m spring at TM for shear mode

                app.Ktmb = 0.05; 1.0e3*exp(-380*x_location)*D_x * 60;   % N/m spring at TM for bending mode

                app.Krl  = 0.04; 5.4e3*exp(-380*x_location)*D_x * 3; % N/m spring at the RL-PC attachment

                Mtm  = 7e-7*exp(160*x_location)*D_x*2; % kg

                app.Mtmb = 4e-7*exp(160*x_location)*D_x*2; % kg

                Mfluid = 4.5e-11;      % resonance around 13.57kHz

                tau_nl =  0;

                app.iq =   1*1.080;   % Gmet_max = 90 [nS]; phi_ohc = 150 [mV]

                app.Rm_lin = 2.808e+07;  5.8e5;

                app.Ra0 = 1/(27e-9)/3;     58.82*exp(300*x_location)/D_x/3;

                app.Ca =  1.4e-12*3;   % F

                app.Cm_lin = 8.7e-12;

                app.c_nl = 9.5e-12;

                app.Cm_total = app.Cm_lin+ app.c_nl;

                app.sigma_hb = 0.076;

                app.G_hb_max = 95;

            end

            app.Mbm = Mbm1+Mfluid ;

            app.Mtms = Mtm;

            Ya = (1/app.Ra0+s*app.Ca);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% damping %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.csub = 1e-3*app.Ltm/app.Lst*(D_x);  % [N*s/m] subtectorial space damping

            app.cTMS =  8.000000000000000e-07/2;  % [N*s/m]

            app.cBM  =  5.000000000000001e-07/5;   % [N*s/m]

            app.cTMB =  4.000000000000000e-07/4;    % [N*s/m]

            app.cOHC =  1.600000000000000e-07;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% OHC time rate %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            kappa_ohc = 1/app.k_ohc;   % [m/N], OHC compliance static

            kappa_nl = 10;   % approximated by boltzmann distribution

            kappa_e = kappa_ohc-kappa_nl;

            k_e = 1/kappa_e;  % [N/m], stiffness of elastic part

            k_nl = 1/kappa_nl;   % [N/m], stiffness of motor part

            d_nl = -1.1e-7*kappa_ohc;   %[N/m]

            tau_e = 0.0*tau_nl;    % 10mm

            kappa_ohc_tilde = kappa_e/(1+s*tau_e)+kappa_nl/(1+s*tau_nl);

            k_ohc_tilde = 1/kappa_ohc_tilde;

            Kohc = k_ohc_tilde;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% electric parameters %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            d_nl_tilde = d_nl/(1+s*tau_nl);

            c_mnl_tilde = app.c_nl/(1+s*tau_nl)-d_nl_tilde^2*Kohc;

            Ym = 3*(1/app.Rm_lin+s*app.Cm_lin)+3*c_mnl_tilde*s;

            app.eps3 = epsLevel*d_nl_tilde*Kohc;

            app.Rtl = 4/D_x;        %Ohm

            app.Rvm = 25/D_x;      %Ohm

            app.Rvl = 10/D_x;      %Ohm
            % 
            Cr1 = (1/app.Rvm)/(1/app.Rvl+1/app.Rvm);

            Cr2 = (1/app.Rtl)/((Cr1-1)/app.Rvm);

            Y_all = 1/(Ya+Ym+app.Rtl*Ya*Ym*(1-Cr2));

            num = 1000;

            freq_all = logspace(0,5,num);

            Omega_value = nan(num,1);

            Y_all_value = nan(num,1);

            for iii = 1:num

                freq = freq_all(iii);

                s = 2*pi*freq*j;

                Y_value = evalfr(Y_all,s);

                eps_value = evalfr(app.eps3,s);

                Y_all_value(iii) = Y_value;

                Omega_value(iii) = -3*eps_value*app.iq*Y_value;

            end
            %%

            figure('Visible', 'off');

            loglog(app.UIAxes4, freq_all, 2*pi*freq_all'.*(abs(Omega_value)),'linewidth',2); hold(app.UIAxes,'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'BM response')
            xlabel(app.UIAxes, 'frequency [Hz]')
            ylabel(app.UIAxes, 'amplitude [nm/nN]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'TMS response')
            xlabel(app.UIAxes2, 'frequency [Hz]')
            ylabel(app.UIAxes2, 'amplitude [nm/nN]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'TMB response')
            xlabel(app.UIAxes3, 'frequency [Hz]')
            ylabel(app.UIAxes3, 'amplitude [nm/nN]')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'maximum power filter function')
            xlabel(app.UIAxes4, 'frequency [Hz]')
            ylabel(app.UIAxes4, 'MPF magnitude')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.OoC_Image = uiimage(app.UIFigure);
            app.OoC_Image.ImageSource = fullfile(pathToMLAPP, 'OoC_model.jpg');
            app.show_para_Button = uibutton(app.UIFigure, 'push');
            app.show_para_Button.ButtonPushedFcn = createCallbackFcn(app, @show_para_ButtonPushed, true);
            app.show_para_Button.Text = 'show parameters value';
            app.Parameter_table = uitable(app.UIFigure);
            app.Parameter_table.ColumnName = {'Parameter'; 'Value'; 'Unit'; 'Description'};
            app.Parameter_table.RowName = {};
            app.Parameter_table.ColumnEditable = true;
            app.Parameter_table.CellEditCallback = createCallbackFcn(app, @Parameter_tableCellEdit2, true);
            app.Parameter_table.DisplayDataChangedFcn = createCallbackFcn(app, @Parameter_tableDisplayDataChanged, true);
            app.title_text = uilabel(app.UIFigure);
            app.title_text.Text = 'Cochlear Cross-sectional Organ of Corti Model (Guinea Pig)';
            app.plotresponsesButton = uibutton(app.UIFigure, 'push');
            app.plotresponsesButton.ButtonPushedFcn = createCallbackFcn(app, @plotresponsesButtonPushed, true);
            app.plotresponsesButton.Text = 'plot responses';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'OoC_electric_model.jpg');
            app.xmmLabel = uilabel(app.UIFigure);
            app.xmmLabel.Text = 'x [mm]  ';
            app.xmmDropDown = uidropdown(app.UIFigure);
            app.xmmDropDown.Items = {'location', '4.15 ', '7.5 ', '12.5'};
            app.xmmDropDown.Value = 'location';
            app.plotMPFfunctionButton = uibutton(app.UIFigure, 'push');
            app.plotMPFfunctionButton.ButtonPushedFcn = createCallbackFcn(app, @plotMPFfunctionButtonPushed, true);
            app.plotMPFfunctionButton.Text = 'plot MPF function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OoC_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end